classdef DASTgaze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotPanel                       matlab.ui.container.Panel
        UIAxesRight                     matlab.ui.control.UIAxes
        UIAxesLeft                      matlab.ui.control.UIAxes
        EyeDataPanel                    matlab.ui.container.Panel
        NoisebeforetargetsEditField     matlab.ui.control.EditField
        NoisebeforetargetsEditFieldLabel  matlab.ui.control.Label
        SilencebeforenoisesEditField    matlab.ui.control.EditField
        SilencebeforenoisesEditFieldLabel  matlab.ui.control.Label
        DeleteifdataismissingEditField  matlab.ui.control.EditField
        DeleteifdataismissingEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PlotButton                      matlab.ui.control.Button
        PreprocessingSettingsPanel      matlab.ui.container.Panel
        LowPassWindowsEditField         matlab.ui.control.EditField
        LowPassWindowsEditFieldLabel    matlab.ui.control.Label
        MinimumLengthNanRepairEditField  matlab.ui.control.EditField
        MinimumLengthNanRepairEditFieldLabel  matlab.ui.control.Label
        RemoveBeforeAndAftermsEditField  matlab.ui.control.EditField
        RemoveBeforeAndAftermsEditFieldLabel  matlab.ui.control.Label
        GazeDataSamplingFrequencyHzEditField  matlab.ui.control.EditField
        GazeDataSamplingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        DifferenceThresholdEditField    matlab.ui.control.EditField
        DifferenceThresholdEditFieldLabel  matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.EditField
        RangeEditFieldLabel             matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        CheckIfGazeDataispresentButton  matlab.ui.control.Button
        UploadedDataEditField           matlab.ui.control.EditField
        LoadPresetButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        plotData    % Description
        Param       %settings parameters
    end
    
    methods (Access = private)
        
        function CheckFieldNames(app)
            
            s = app.plotData.GazeData.GazeStruct(1).SubGazeStruct;

            fields = fieldnames(s);
            left_single_fields = {};
            
            for i = 1:numel(fields)
                if startsWith(fields{i}, 'left') && isa(s.(fields{i}), 'single')
                    left_single_fields{end+1} = fields{i}; %#ok<AGROW>
                end
            end
            
            disp(left_single_fields);
        end
        
        function [left,right] = ExtractEyeData(app,data)

            N = numel(data.GazeStruct);             % number of top-level structs
            lens = zeros(N,1);
            
            for i = 1:N
                % assume SubGazeStruct is a struct array as well
                % and we’re only interested in the first element? or all?
                lens(i) = length(data.GazeStruct(i).SubGazeStruct.left_pupil_diameter);
            end
            
            Left = nan(numel(data.GazeStruct),max(lens));
            Right = nan(numel(data.GazeStruct),max(lens));
            
            for i = 1:N
                data_L = data.GazeStruct(i).SubGazeStruct.left_pupil_diameter;
                data_R = data.GazeStruct(i).SubGazeStruct.right_pupil_diameter;
            
                left(i,1:length(data_L)) = data_L;
                right(i,1:length(data_R)) = data_R;
            end
            
            clear data_R data_L lens N i 

        end
        
        function [Diameter,Metadata] = setnan(app,RawDiameter,Param)

            %SETTONAN sets values in RawDiameter to NaN based on criteria defined in the structure Param. 
            %   Detailed explanation goes here
            %
            %   Inputs:
            %       RawDiameter: One-dimensional vector with values and NaNs as missing
            %       values
            %       Param: Structure with some necessary parameters, for example: 
            %           Param.Range = .1 1; % [mm] or [Pixels]: Set values outside this range to NaN
            %           Param.RangeQuantile = true; If true, Range is interpreted as
            %           quantile and should be between 0 and 1.
            %           Param.DiffThresh = 0.9; % [mm] or [Pixels]: If the difference
            %           from one sample to the next sample exceeds this thereshold, both samples
            %           will be set to NaN.
            %           Param.DiffQuantile = true; If true, DiffThresh is interpreted
            %           as quantile and should be between 0 and 1.
            %   Outputs: 
            %       Diameter: NaN'ed Diameter
            %       Metadata: Some information about missing data a.s.o
            %           Metadata.IsNanRaw: loigcal vector indicating where the raw data
            %           had NaN's
            %           Metadata.Range: Which range criteria was applied to the data.
            %           Metadata.SetNanRange: logical vector with 1's indicating which 
            %           samples were set to NaN because of range criterion.
            %           Metadata.DiffThresh: Which difference criterion was applied to the data.
            %           Metadata.SetNanDiff: logical vector with 1's indicating which 
            %           samples were set to NaN because of difference criterion.
            
            % Make sure its a column vector
            if size(RawDiameter,2) > size(RawDiameter,1)
                RawDiameter = RawDiameter';
            end
            
            % Make sure it is only one column
            if size(RawDiameter,2) > 1
                error('Please use a one-dimensional vector')
            end
            
            % Make sure Range is [min max]
            if app.Param.Range(1) >= app.Param.Range(2) || length(app.Param.Range) ~= 2 
                error('Please provide a range vector with two elements where Range(1) < Range(2)')
            end

            
            % Get missing samples of raw data
            Metadata.IsNanRaw = isnan(RawDiameter); 
            
            % Apply range criterion

            Metadata.Range = Param.Range;

            Metadata.SetNanRange = RawDiameter < Metadata.Range(1)| RawDiameter > Metadata.Range(2);
            
            % Apply difference criterion
            Diff = abs(diff(RawDiameter)); % Calculate aboslite difference
            if ~Param.DiffQuantile 
                Metadata.DiffThresh = Param.DiffThresh;
            elseif Param.DiffQuantile
                SortDiff = sort(Diff(~isnan(Diff)));
                Metadata.DiffThresh = SortDiff(round(Param.DiffThresh*length(SortDiff)));
            end
            Metadata.SetNanDiff = [Diff > Metadata.DiffThresh | [0; Diff(1:(end-1))] > Metadata.DiffThresh; 0];
            
            % Set samples to NaN that either meet the range or the difference criterion
            Metadata.SetNan = Metadata.SetNanRange | Metadata.SetNanDiff;
            RawDiameter(Metadata.SetNan) = nan;
            Diameter = RawDiameter;
            
        end
        
        function RangeRemove(app)

            app.plotData.Ntrials = size(app.plotData.LeftEye,1);

            
            % Preallocate
            app.plotData.Cleaned_Diameter_L = nan(size(app.plotData.LeftEye));
            app.plotData.Cleaned_Diameter_R = nan(size(app.plotData.RightEye));
            app.plotData.Metadata_Cleaning_L = cell(app.plotData.Ntrials,1);
            app.plotData.Metadata_Cleaning_R = cell(app.plotData.Ntrials,1);
            
            app.Param.BaselineSamples = (app.Param.DurationSilence + app.Param.DurationNoise)*app.Param.Fs;
            

            for i = 1:app.plotData.Ntrials
                % Left eye
                [cleanVecL, metaStructL] = app.setnan(app.plotData.LeftEye(i,:), app.Param); % column vector input
                app.plotData.Cleaned_Diameter_L(i,:) = cleanVecL.';                 % store as row
                app.plotData.Metadata_Cleaning_L{i} = metaStructL;                 % store struct in cell
            
                % Right eye
                [cleanVecR, metaStructR] = app.setnan(app.plotData.RightEye(i,:).', app.Param); % column vector input
                app.plotData.Cleaned_Diameter_R(i,:) = cleanVecR.';                  % store as row
                app.plotData.Metadata_Cleaning_R{i} = metaStructR;                  % store struct in cell
            end
            
            clear cleanVecR cleanVecL metaStructL metaStructR


        end
        
        function [Diameter, Metadata] = preprocpupil(app, RawDiameter, Param)
            %PREPROCPUPIL preprocesses pupillometry data based on the parameters defined in Param.
            %   It sets values before and after NaNs to NaN to remove transient artifacts 
            %   (e.g., blinks) and linearly interpolates missing values.
            %
            %   Inputs:
            %       RawDiameter: One-dimensional vector with values and NaNs as missing values
            %       Param: Structure with necessary parameters, for example:
            %           Param.Fs = 50; % [Hz]
            %           Param.RemoveBeforeAndAfter = [35 100]*1e-3; % [s]
            %           Param.MinLengthNanRepair = 1; % [samples]
            %
            %   Outputs: 
            %       Diameter: Preprocessed Diameter
            %       Metadata: Structure with information about NaNs and processing steps
    
            % Ensure it's a column vector
            if size(RawDiameter,2) > size(RawDiameter,1)
                RawDiameter = RawDiameter';
            end
        
            % Ensure it is only one column
            if size(RawDiameter,2) > 1
                error('Please use a one-dimensional vector')
            end
        
            L = length(RawDiameter); % Length of signal
        
            % --- METADATA ---
            Metadata.Time = ((0:(L-1)) / Param.Fs)'; % Time vector
            Metadata.IsnanRaw = isnan(RawDiameter);      % Missing samples
            Metadata.IntactRaw = ~Metadata.IsnanRaw;     % Intact samples
            Metadata.ContigNan = findcontigval(RawDiameter, nan); % Contiguous NaN clusters
        
            % --- FIND CLUSTERS ABOVE THRESHOLD ---
            LengthContigNan = diff(Metadata.ContigNan, 1, 2) + 1;
            RemoveContigNan = Metadata.ContigNan( ...
                find(LengthContigNan >= Param.MinLengthNanRepair), :);
        
            % --- EXPAND WINDOW AROUND NaNs ---
            RemoveContigNan(:,1) = RemoveContigNan(:,1) - ceil(Param.RemoveBeforeAndAfter(1)*Param.Fs);
            RemoveContigNan(:,2) = RemoveContigNan(:,2) + ceil(Param.RemoveBeforeAndAfter(2)*Param.Fs);
        
            % --- LIMIT TO VALID RANGE ---
            RemoveContigNan = max(RemoveContigNan, 1);
            RemoveContigNan = min(RemoveContigNan, length(RawDiameter));
        
            % --- SET TO NAN ---
            Diameter = RawDiameter;
            Metadata.SetNan = false(size(Diameter));
            for idx = 1:size(RemoveContigNan,1)
                Metadata.SetNan(RemoveContigNan(idx,1):RemoveContigNan(idx,2)) = true;
            end
            Diameter(Metadata.SetNan) = nan;
        
            % --- UPDATE MASKS ---
            Metadata.Isnan = isnan(Diameter);
            Metadata.Intact = ~Metadata.Isnan;
        
            % --- INTERPOLATE ---
            if sum(Metadata.Intact) > 1
                Diameter(Metadata.Isnan) = interp1( ...
                    Metadata.Time(Metadata.Intact), ...
                    Diameter(Metadata.Intact), ...
                    Metadata.Time(Metadata.Isnan));
        
                % Handle edge NaNs
                EdgeNans = findcontigval(Diameter, nan);
                for idx = 1:size(EdgeNans,1)
                    if EdgeNans(idx,1) == 1
                        Diameter(EdgeNans(idx,1):EdgeNans(idx,2)) = Diameter(EdgeNans(idx,2)+1);
                    elseif EdgeNans(idx,2) == L
                        Diameter(EdgeNans(idx,1):EdgeNans(idx,2)) = Diameter(EdgeNans(idx,1)-1);
                    end
                end
            else
                warning('No data left after removal of samples.')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)
            
            dirname = append(pwd, '\results');
            [filename,pathname] = uigetfile(fullfile(dirname,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename,'data');
                app.plotData.GazeData = data;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);
                app.UploadedDataEditField.Value = filename;
                drawnow;
                figure(app.UIFigure);

            end

            % Get focus back to the main figure
            focus(app.UIFigure);
        end

        % Button pushed function: CheckIfGazeDataispresentButton
        function CheckIfGazeDataispresentButtonPushed(app, event)
            if isfield(app.plotData.GazeData, 'GazeStruct')
                app.Lamp.Color = [0 1 0];
                app.PreprocessingSettingsPanel.Visible = "on";
                app.EyeDataPanel.Visible = "on";
            else 
                app.Lamp.Color = [1 0 0];
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            %Cleaning 
            app.Param.Range = str2double(split(erase(app.RangeEditField.Value, ["[", "]"]))).'; % e.g. [mm] or quantile: Set values outside this range to NaN
            app.Param.DiffThresh = str2double(app.DifferenceThresholdEditField.Value); % [mm] or [Pixels]: If the difference from one sample to the next sample exceeds this thereshold, both samples will be set to NaN.
            if app.CheckBox.Value == true
                app.Param.DiffQuantile = 1;
            else
                app.Param.DiffQuantile = 0;
            end
            
            % Preprocessing
            app.Param.Fs = str2double(app.GazeDataSamplingFrequencyHzEditField.Value); % [Hz]: Sampling rate of pupil data
            app.Param.RemoveBeforeAndAfter = str2double(split(erase(app.RemoveBeforeAndAftermsEditField.Value, ["[", "]"]))).'*1e-3; % [s]: Samples within the time ramge before and after NaNs will set NaNs as well
            app.Param.MinLengthNanRepair = str2double(app.MinimumLengthNanRepairEditField.Value); % [samples]: Drop values (i.e., change to NaN) before and after NaNs only for contiguous NaNs of at least __ samples.
            app.Param.LPWinSize = str2double(app.LowPassWindowsEditField.Value); % [s]: Window size of hamming-window for low-pass filtering

            app.Param.DelThresh = str2double(app.DeleteifdataismissingEditField.Value);

            app.Param.DurationSilence = str2double(app.SilencebeforenoisesEditField.Value);
            app.Param.DurationNoise = str2double(app.NoisebeforetargetsEditField.Value);

            app.PlotPanel.Visible = 'on';

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            cla(app.UIAxesLeft);
            cla(app.UIAxesRight);

            [app.plotData.LeftEye,app.plotData.RightEye] = app.ExtractEyeData(app.plotData.GazeData);

           
            app.plotData.Ntrials = size(app.plotData.LeftEye,1);

            
            % Preallocate
            app.plotData.Cleaned_Diameter_L = nan(size(app.plotData.LeftEye));
            app.plotData.Cleaned_Diameter_R = nan(size(app.plotData.RightEye));
            app.plotData.Metadata_Cleaning_L = cell(app.plotData.Ntrials,1);
            app.plotData.Metadata_Cleaning_R = cell(app.plotData.Ntrials,1);
            
            app.Param.BaselineSamples = (app.Param.DurationSilence + app.Param.DurationNoise)*app.Param.Fs;
            

            for i = 1:app.plotData.Ntrials
                % Left eye
                [cleanVecL, metaStructL] = app.setnan(app.plotData.LeftEye(i,:), app.Param); % column vector input
                app.plotData.Cleaned_Diameter_L(i,:) = cleanVecL.';                 % store as row
                app.plotData.Metadata_Cleaning_L{i} = metaStructL;                 % store struct in cell
            
                % Right eye
                [cleanVecR, metaStructR] = app.setnan(app.plotData.RightEye(i,:).', app.Param); % column vector input
                app.plotData.Cleaned_Diameter_R(i,:) = cleanVecR.';                  % store as row
                app.plotData.Metadata_Cleaning_R{i} = metaStructR;                  % store struct in cell
            end
            
            clear cleanVecR cleanVecL metaStructL metaStructR


            % Preallocate
            app.plotData.Repaired_Diameter_L = nan(size(app.plotData.LeftEye));
            app.plotData.Repaired_Diameter_R = nan(size(app.plotData.RightEye));
            app.plotData.Metadata_L = cell(app.plotData.Ntrials,1);
            app.plotData.Metadata_R = cell(app.plotData.Ntrials,1);
            
            %pre porcessing 
            
            for i = 1:app.plotData.Ntrials
                % Left eye
                [cleanVecL, metaStructL] = app.preprocpupil(app.plotData.Cleaned_Diameter_L(i,:),app.Param);
                app.plotData.Repaired_Diameter_L(i,:) = cleanVecL.';                 % store as row
                app.plotData.Metadata_L{i} = metaStructL;                 % store struct in cell
            
                % Right eye
                [cleanVecR, metaStructR] = app.preprocpupil(app.plotData.Cleaned_Diameter_R(i,:).', app.Param); % column vector input
                app.plotData.Repaired_Diameter_R(i,:) = cleanVecR.';                  % store as row
                app.plotData.Metadata_R{i} = metaStructR;                  % store struct in cell
            end


            app.Param.Baseline_L = mean(app.plotData.Cleaned_Diameter_L(:,1:app.Param.BaselineSamples),2,"omitmissing");
            app.Param.Baseline_R = mean(app.plotData.Cleaned_Diameter_R(:,1:app.Param.BaselineSamples),2,"omitmissing");

            app.plotData.Cleaned_Diameter_L_B = app.plotData.Cleaned_Diameter_L - app.Param.Baseline_L;
            app.plotData.Cleaned_Diameter_R_B = app.plotData.Cleaned_Diameter_R - app.Param.Baseline_R;

            app.plotData.Repaired_Diameter_L_B = app.plotData.Repaired_Diameter_L - app.Param.Baseline_L;
            app.plotData.Repaired_Diameter_R_B = app.plotData.Repaired_Diameter_R - app.Param.Baseline_R;


            app.plotData.t = (0:1:size(app.plotData.LeftEye,2)-1)./app.Param.Fs;

            app.plotData.t_B = app.plotData.t(app.Param.BaselineSamples:end);
            plot(app.UIAxesLeft,app.plotData.t_B,app.plotData.Cleaned_Diameter_L_B(1,app.Param.BaselineSamples:end))
            hold(app.UIAxesLeft, 'on')
            plot(app.UIAxesLeft,app.plotData.t_B,app.plotData.Repaired_Diameter_L_B(1,app.Param.BaselineSamples:end), "LineStyle","--")
            
            
            plot(app.UIAxesRight,app.plotData.t_B,app.plotData.Cleaned_Diameter_R_B(1,app.Param.BaselineSamples:end))
            hold(app.UIAxesRight, 'on')
            plot(app.UIAxesRight,app.plotData.t_B,app.plotData.Repaired_Diameter_R_B(1,app.Param.BaselineSamples:end), "LineStyle","--")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadPresetButton = uibutton(app.UIFigure, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load eye data';
            app.UploadedDataEditField = uieditfield(app.UIFigure, 'text');
            app.UploadedDataEditField.Editable = 'off';
            app.CheckIfGazeDataispresentButton = uibutton(app.UIFigure, 'push');
            app.CheckIfGazeDataispresentButton.ButtonPushedFcn = createCallbackFcn(app, @CheckIfGazeDataispresentButtonPushed, true);
            app.CheckIfGazeDataispresentButton.Text = 'Check If Gaze Data is present';
            app.Lamp = uilamp(app.UIFigure);
            app.PreprocessingSettingsPanel = uipanel(app.UIFigure);
            app.PreprocessingSettingsPanel.Title = 'Preprocessing Settings';
            app.PreprocessingSettingsPanel.Visible = 'off';
            app.RangeEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.RangeEditFieldLabel.Text = 'Range ';
            app.RangeEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.RangeEditField.Tooltip = {' [mm] Set values outside this range to NaN'};
            app.RangeEditField.Value = '[2 2.5]';
            app.DifferenceThresholdEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.DifferenceThresholdEditFieldLabel.Text = 'Difference Threshold';
            app.DifferenceThresholdEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.DifferenceThresholdEditField.Tooltip = {' [mm] or quantile: If the difference from one sample to the next sample exceeds this thereshold, both samples will be set to NaN.'};
            app.DifferenceThresholdEditField.Value = '0.95';
            app.CheckBox = uicheckbox(app.PreprocessingSettingsPanel);
            app.CheckBox.Tooltip = {'% If marked, Difference Threshold is interpreted as quantile and should be between 0 and 1.'};
            app.CheckBox.Text = '%';
            app.CheckBox.Value = true;
            app.GazeDataSamplingFrequencyHzEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.GazeDataSamplingFrequencyHzEditFieldLabel.Text = 'Gaze Data Sampling Frequency [Hz] ';
            app.GazeDataSamplingFrequencyHzEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.GazeDataSamplingFrequencyHzEditField.Tooltip = {' Sampling rate of pupil data'};
            app.GazeDataSamplingFrequencyHzEditField.Value = '60';
            app.RemoveBeforeAndAftermsEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.RemoveBeforeAndAftermsEditFieldLabel.Text = 'Remove Before And After [ms]';
            app.RemoveBeforeAndAftermsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.RemoveBeforeAndAftermsEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.RemoveBeforeAndAftermsEditField.Value = '[35 100]';
            app.MinimumLengthNanRepairEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.MinimumLengthNanRepairEditFieldLabel.Text = 'Minimum Length Nan Repair';
            app.MinimumLengthNanRepairEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.MinimumLengthNanRepairEditField.Tooltip = {'Drop values (i.e., change to NaN) before and after NaNs only for contiguous NaNs of at least __ samples.'};
            app.MinimumLengthNanRepairEditField.Value = '10';
            app.LowPassWindowsEditFieldLabel = uilabel(app.PreprocessingSettingsPanel);
            app.LowPassWindowsEditFieldLabel.Text = 'Low Pass  Window [s]';
            app.LowPassWindowsEditField = uieditfield(app.PreprocessingSettingsPanel, 'text');
            app.LowPassWindowsEditField.Tooltip = {' Window size of hamming-window for low-pass filtering'};
            app.LowPassWindowsEditField.Value = '.1';
            app.EyeDataPanel = uipanel(app.UIFigure);
            app.EyeDataPanel.Title = 'Eye Data';
            app.EyeDataPanel.Visible = 'off';
            app.PlotButton = uibutton(app.EyeDataPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SaveButton = uibutton(app.EyeDataPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DeleteifdataismissingEditFieldLabel = uilabel(app.EyeDataPanel);
            app.DeleteifdataismissingEditFieldLabel.Text = 'Delete if % data is missing';
            app.DeleteifdataismissingEditField = uieditfield(app.EyeDataPanel, 'text');
            app.DeleteifdataismissingEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.DeleteifdataismissingEditField.Value = '30';
            app.SilencebeforenoisesEditFieldLabel = uilabel(app.EyeDataPanel);
            app.SilencebeforenoisesEditFieldLabel.Text = 'Silence before noise  [s]';
            app.SilencebeforenoisesEditField = uieditfield(app.EyeDataPanel, 'text');
            app.SilencebeforenoisesEditField.CharacterLimits = [0 3];
            app.SilencebeforenoisesEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.SilencebeforenoisesEditField.Value = '0.5';
            app.NoisebeforetargetsEditFieldLabel = uilabel(app.EyeDataPanel);
            app.NoisebeforetargetsEditFieldLabel.Text = 'Noise before target [s]';
            app.NoisebeforetargetsEditField = uieditfield(app.EyeDataPanel, 'text');
            app.NoisebeforetargetsEditField.Tooltip = {'Samples within the time ramge before and after NaNs will set NaNs as well'};
            app.NoisebeforetargetsEditField.Value = '3';
            app.PlotPanel = uipanel(app.UIFigure);
            app.PlotPanel.Title = 'Panel';
            app.PlotPanel.Visible = 'off';
            app.UIAxesLeft = uiaxes(app.PlotPanel);
            title(app.UIAxesLeft, 'Left Eye')
            xlabel(app.UIAxesLeft, 'Time [s]')
            ylabel(app.UIAxesLeft, 'Pupil Diameter [mm]')
            zlabel(app.UIAxesLeft, 'Z')
            app.UIAxesRight = uiaxes(app.PlotPanel);
            title(app.UIAxesRight, 'RightEye')
            xlabel(app.UIAxesRight, 'Time [s]')
            ylabel(app.UIAxesRight, 'Pupil Diameter [mm]')
            zlabel(app.UIAxesRight, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DASTgaze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end